classdef SimpleStepper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UserNotesTextAreaLabel          matlab.ui.control.Label
        UserNotesTextArea               matlab.ui.control.TextArea
        FrameperTriggerLabel            matlab.ui.control.Label
        FrameperTriggerSlider           matlab.ui.control.Slider
        ofMeasurementEditFieldLabel     matlab.ui.control.Label
        ofMeasurementEditField          matlab.ui.control.NumericEditField
        FileNameTextAreaLabel           matlab.ui.control.Label
        FileNameTextArea                matlab.ui.control.TextArea
        FilePathTextAreaLabel           matlab.ui.control.Label
        FilePathTextArea                matlab.ui.control.TextArea
        SelectCSVwWLExposureButton      matlab.ui.control.Button
        CameramodeBINEditFieldLabel     matlab.ui.control.Label
        CameramodeBINEditField          matlab.ui.control.NumericEditField
        RegionofInterestLabel           matlab.ui.control.Label
        HorizontalOffsetEditFieldLabel  matlab.ui.control.Label
        HorizontalOffsetEditField       matlab.ui.control.EditField
        VerticalOffsetEditFieldLabel    matlab.ui.control.Label
        VerticalOffsetEditField         matlab.ui.control.EditField
        HorizontalSizeEditFieldLabel    matlab.ui.control.Label
        HorizontalSizeEditField         matlab.ui.control.EditField
        VerticalSizeEditFieldLabel      matlab.ui.control.Label
        VerticalSizeEditField           matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        InstrumentStatus                matlab.ui.control.Label
        InitializeMeasurementButton     matlab.ui.control.Button
        StartMeasurementButton          matlab.ui.control.Button
        TestWavelengthEditFieldLabel    matlab.ui.control.Label
        TestWavelengthEditField         matlab.ui.control.NumericEditField
        TestExposuremsEditFieldLabel    matlab.ui.control.Label
        TestExposuremsEditField         matlab.ui.control.NumericEditField
        UpdateCameraButton              matlab.ui.control.Button
        UpdateMonoButton                matlab.ui.control.Button
        ShutterButton                   matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private) %Init values that are changed thru call backs
        CSV_fp = ''; %file path for CSV
        fn = ''; %save file name for h5
        fp = '';  %save file path for h5
        framesPerTrigger = 1; % default value for frames per trigger
        mode = 1; %default value for camera binning
        H_offset = 0; 
        V_offset = 0;
        cam_width = 1024; %default value
        cam_height = 1024; %default value
        usernotes = ''; %init user notes
        num_meas = 64; %init number of measurements for stepper
        vid %init vid obj 
        COMmono %init mono obj
        dq
        ch
        test_wl = 400; 
        test_expos = 1; 
        src %camera source
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectCSVwWLExposureButton
        function CSV_sel(app, event)
            %When the specific button is pressed the commands will happen
            %bellow
            [file,path] = uigetfile('*.csv');
            if ischar(file)
                app.InstrumentStatus.Text = {'User Selected CSV'};
                app.CSV_fp = fullfile(path,file); 
            else
                app.InstrumentStatus.Text = {'User Did not Select File'};
            end
        end

        % Value changed function: FileNameTextArea
        function save_fn(app, event)
            app.fn = app.FileNameTextArea.Value;
            
        end

        % Value changed function: FilePathTextArea
        function save_fp(app, event)
            app.fp = app.FilePathTextArea.Value;
            
        end

        % Value changed function: FrameperTriggerSlider
        function trigNum(app, event)
            app.framesPerTrigger = app.FrameperTriggerSlider.Value;
            
        end

        % Value changed function: CameramodeBINEditField
        function changeCamMode(app, event)
            app.mode = app.CameramodeBINEditField.Value;
            
        end

        % Value changed function: HorizontalOffsetEditField
        function Set_Hoffset(app, event)
            app.H_offset = app.HorizontalOffsetEditField.Value;
            
        end

        % Value changed function: VerticalOffsetEditField
        function Set_Voffset(app, event)
            app.V_ofset = app.VerticalOffsetEditField.Value;
            
        end

        % Value changed function: HorizontalSizeEditField
        function Set_wsize(app, event)
            app.cam_width = app.HorizontalSizeEditField.Value;
            
        end

        % Value changed function: VerticalSizeEditField
        function Set_hsize(app, event)
            app.cam_height = app.VerticalSizeEditField.Value;
            
        end

        % Value changed function: UserNotesTextArea
        function Set_usernotes(app, event)
            app.usernotes = app.UserNotesTextArea.Value;
            
        end

        % Value changed function: ofMeasurementEditField
        function Set_numMeas(app, event)
            app.num_meas = app.ofMeasurementEditField.Value;
            
        end

        % Button pushed function: InitializeMeasurementButton
        function init_Stepper(app, event)
            ROIPosition = [app.H_offset, app.V_offset,app.cam_width, app.cam_height];
            app.vid = initializeCamera(app.mode,ROIPosition,app.framesPerTrigger); 
            % app.vid might not be happy
            app.COMmono = initializeMono();
            [app.dq,app.ch] = initializeRef();
             
            %IDK if I need to add more here mads
        end

        % Value changed function: ShutterButton
        function ShutterButtonValueChanged(app, event)
            value = app.ShutterButton.Value;
            NI_shutter_Jake(value)
        end

        % Value changed function: TestWavelengthEditField
        function TestWavelengthEditFieldValueChanged(app, event)
            app.test_wl = app.TestWavelengthEditField.Value;
            
        end

        % Value changed function: TestExposuremsEditField
        function TestExposuremsEditFieldValueChanged(app, event)
            app.test_expos = app.TestExposuremsEditField.Value;
            
        end

        % Button pushed function: UpdateMonoButton
        function UpdateMono(app, event)
            changeWavelength(app.COMmono,app.test_wl)
            app.InstrumentStatus.Text = {['WL Set to ', num2str(app.test_wl) ]}; 
        end

        % Button pushed function: UpdateCameraButton
        function UpdateCam(app, event)
            %% Add camera stuff here
            app.src = getselectedsource(app.vid); 
            triggerconfig(app.vid, 'imediate');
            app.vid.FramesPerTrigger = app.framesPerTrigger;
            app.src.ExposureTimeControl = 'normal';
            app.src.ExposureTime = app.test_expos;
            start(app.vid)
            im = getdata(app.vid);
            stop(app.vid);
            
            image = im(:,:,1,1);
            for i = 2:app.framesPerTrigger
                image = image + im(:,:,1,i);
            end
            image = image/app.framesPerTrigger;
            imshow(image,'Parent',app.UIAxes);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UserNotesTextAreaLabel = uilabel(app.LeftPanel);
            app.UserNotesTextAreaLabel.Text = 'User Notes';
            app.UserNotesTextArea = uitextarea(app.LeftPanel);
            app.UserNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @Set_usernotes, true);
            app.FrameperTriggerLabel = uilabel(app.LeftPanel);
            app.FrameperTriggerLabel.Text = {'Frame'; 'per '; 'Trigger'};
            app.FrameperTriggerSlider = uislider(app.LeftPanel);
            app.FrameperTriggerSlider.Limits = [1 11];
            app.FrameperTriggerSlider.MajorTicks = [1 3 5 7 9 11];
            app.FrameperTriggerSlider.ValueChangedFcn = createCallbackFcn(app, @trigNum, true);
            app.FrameperTriggerSlider.MinorTicks = [2 4 6 8 10];
            app.FrameperTriggerSlider.Value = 1;
            app.ofMeasurementEditFieldLabel = uilabel(app.LeftPanel);
            app.ofMeasurementEditFieldLabel.Text = '# of Measurement';
            app.ofMeasurementEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ofMeasurementEditField.ValueChangedFcn = createCallbackFcn(app, @Set_numMeas, true);
            app.ofMeasurementEditField.Value = 64;
            app.FileNameTextAreaLabel = uilabel(app.LeftPanel);
            app.FileNameTextAreaLabel.Text = 'File Name';
            app.FileNameTextArea = uitextarea(app.LeftPanel);
            app.FileNameTextArea.ValueChangedFcn = createCallbackFcn(app, @save_fn, true);
            app.FilePathTextAreaLabel = uilabel(app.LeftPanel);
            app.FilePathTextAreaLabel.Text = 'File Path';
            app.FilePathTextArea = uitextarea(app.LeftPanel);
            app.FilePathTextArea.ValueChangedFcn = createCallbackFcn(app, @save_fp, true);
            app.SelectCSVwWLExposureButton = uibutton(app.LeftPanel, 'push');
            app.SelectCSVwWLExposureButton.ButtonPushedFcn = createCallbackFcn(app, @CSV_sel, true);
            app.SelectCSVwWLExposureButton.Text = 'Select CSV w/ WL & Exposure';
            app.CameramodeBINEditFieldLabel = uilabel(app.LeftPanel);
            app.CameramodeBINEditFieldLabel.Text = 'Camera mode/BIN';
            app.CameramodeBINEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CameramodeBINEditField.ValueChangedFcn = createCallbackFcn(app, @changeCamMode, true);
            app.CameramodeBINEditField.Value = 1;
            app.RegionofInterestLabel = uilabel(app.LeftPanel);
            app.RegionofInterestLabel.Text = 'Region of Interest';
            app.HorizontalOffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.HorizontalOffsetEditFieldLabel.Text = 'Horizontal Offset';
            app.HorizontalOffsetEditField = uieditfield(app.LeftPanel, 'text');
            app.HorizontalOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @Set_Hoffset, true);
            app.HorizontalOffsetEditField.Value = '0';
            app.VerticalOffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.VerticalOffsetEditFieldLabel.Text = 'Vertical Offset';
            app.VerticalOffsetEditField = uieditfield(app.LeftPanel, 'text');
            app.VerticalOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @Set_Voffset, true);
            app.VerticalOffsetEditField.Value = '0';
            app.HorizontalSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.HorizontalSizeEditFieldLabel.Text = 'Horizontal Size';
            app.HorizontalSizeEditField = uieditfield(app.LeftPanel, 'text');
            app.HorizontalSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Set_wsize, true);
            app.HorizontalSizeEditField.Value = '1024';
            app.VerticalSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.VerticalSizeEditFieldLabel.Text = 'Vertical Size';
            app.VerticalSizeEditField = uieditfield(app.LeftPanel, 'text');
            app.VerticalSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Set_hsize, true);
            app.VerticalSizeEditField.Value = '1024';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Current Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.InstrumentStatus = uilabel(app.RightPanel);
            app.InstrumentStatus.Text = 'Instrument Status: Not Initialized';
            app.InitializeMeasurementButton = uibutton(app.RightPanel, 'push');
            app.InitializeMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @init_Stepper, true);
            app.InitializeMeasurementButton.Text = {'Initialize Measurement'; ''};
            app.StartMeasurementButton = uibutton(app.RightPanel, 'push');
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.TestWavelengthEditFieldLabel = uilabel(app.RightPanel);
            app.TestWavelengthEditFieldLabel.Text = 'Test Wavelength';
            app.TestWavelengthEditField = uieditfield(app.RightPanel, 'numeric');
            app.TestWavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @TestWavelengthEditFieldValueChanged, true);
            app.TestWavelengthEditField.Value = 400;
            app.TestExposuremsEditFieldLabel = uilabel(app.RightPanel);
            app.TestExposuremsEditFieldLabel.Text = 'Test Exposure (ms)';
            app.TestExposuremsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TestExposuremsEditField.ValueChangedFcn = createCallbackFcn(app, @TestExposuremsEditFieldValueChanged, true);
            app.TestExposuremsEditField.Value = 1;
            app.UpdateCameraButton = uibutton(app.RightPanel, 'push');
            app.UpdateCameraButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCam, true);
            app.UpdateCameraButton.Text = 'Update Camera';
            app.UpdateMonoButton = uibutton(app.RightPanel, 'push');
            app.UpdateMonoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMono, true);
            app.UpdateMonoButton.Text = 'Update Mono';
            app.ShutterButton = uibutton(app.RightPanel, 'state');
            app.ShutterButton.ValueChangedFcn = createCallbackFcn(app, @ShutterButtonValueChanged, true);
            app.ShutterButton.Text = 'Shutter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleStepper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end